classdef interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnlisisderedescerebralesUIFigure  matlab.ui.Figure
        ArchivoMenu                 matlab.ui.container.Menu
        CargarmatrizadyacenciaMenu  matlab.ui.container.Menu
        CargarposicionesnodosMenu   matlab.ui.container.Menu
        SalirMenu                   matlab.ui.container.Menu
        EditarMenu                  matlab.ui.container.Menu
        LimpiardatosMenu            matlab.ui.container.Menu
        VisualizarMenu              matlab.ui.container.Menu
        Visualizar2DMenu            matlab.ui.container.Menu
        Visualizar3DMenu            matlab.ui.container.Menu
        AyudaMenu                   matlab.ui.container.Menu
        InformacinadicionalMenu     matlab.ui.container.Menu
        ContactaconmigoMenu         matlab.ui.container.Menu
        ManualdeusuarioMenu         matlab.ui.container.Menu
        InfoUsuario                 matlab.ui.control.TextArea
        NumNodos                    matlab.ui.control.NumericEditField
        PanelMedidas                matlab.ui.container.TabGroup
        MedidasderedTab             matlab.ui.container.Tab
        Slider                      matlab.ui.control.Slider
        gradoInfo                   matlab.ui.control.NumericEditField
        modularidadInfo             matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        UmbralEditFieldLabel        matlab.ui.control.Label
        UmbralEditField             matlab.ui.control.NumericEditField
        UmbralSliderInfo            matlab.ui.control.Label
        AplicarUmbralButton         matlab.ui.control.Button
        clusteringInfo              matlab.ui.control.NumericEditField
        ExportardatosCSVButton_2    matlab.ui.control.Button
        DimensinFractalPanel        matlab.ui.container.Panel
        Lb_min                      matlab.ui.control.NumericEditField
        Lb_minLabel                 matlab.ui.control.Label
        ResultadoLabel              matlab.ui.control.Label
        ValorDF                     matlab.ui.control.Label
        Lb_maxLabel                 matlab.ui.control.Label
        Lb_max                      matlab.ui.control.NumericEditField
        VisualizargraficaButton     matlab.ui.control.Button
        AplicarboxcoveringButton    matlab.ui.control.Button
        MtodoDropDownLabel          matlab.ui.control.Label
        MtodoDropDown               matlab.ui.control.DropDown
        allFeatures                 matlab.ui.control.Button
        GradomedioLabel             matlab.ui.control.Label
        CoefClusteringLabel         matlab.ui.control.Label
        ModularidadLabel            matlab.ui.control.Label
        MedidasdenodosTab           matlab.ui.container.Tab
        ExportardatosCSVButton      matlab.ui.control.Button
        gradoLocalInfo              matlab.ui.control.NumericEditField
        cercaniaLocalInfo           matlab.ui.control.NumericEditField
        IntermediacionLocalInfo     matlab.ui.control.NumericEditField
        vectorLocalInfo             matlab.ui.control.NumericEditField
        NodoLabel                   matlab.ui.control.Label
        nameNodo                    matlab.ui.control.Label
        SeleccionanodoListBox       matlab.ui.control.ListBox
        SeleccionanodoLabel         matlab.ui.control.Label
        LabelPanelNodos             matlab.ui.control.Label
        CalcularMedidasButton       matlab.ui.control.Button
        GradoLabel                  matlab.ui.control.Label
        CercanaLabel                matlab.ui.control.Label
        IntermediacinLabel          matlab.ui.control.Label
        CtrVectorPropioLabel        matlab.ui.control.Label
        LabelNodos                  matlab.ui.control.Label
        LabelEnlaces                matlab.ui.control.Label
        NumEnlaces                  matlab.ui.control.NumericEditField
        MensajeNoDatos              matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        VisualizadorMatriz          matlab.ui.control.Image
    end

    
    properties (Access = private)
        controlador = "";
    end
    
    methods (Access = private)
        
        function errorCarga = iniciaPanel(app)
            
            app.controlador = dataControler();
            errorCarga = true;
            
            if(app.controlador.cargadoDatosRed == 0)
               errorCarga = false;
            end
            
        end
        
        function visualizaMatriz(app)
            app.VisualizadorMatriz.Visible = 'on';
            id = app.controlador.centinelaImagen();
            app.VisualizadorMatriz.ImageSource = app.controlador.pintaMatriz(id);
        end
        
        function LimpiarDatos(app)
            app.VisualizadorMatriz.Enable = 'off';
            app.gradoInfo.Value = 0; 
            app.clusteringInfo.Value = 0;
            app.modularidadInfo.Value = 0;
            app.ValorDF.Text = "-----";
            app.gradoLocalInfo.Value = 0;
            app.cercaniaLocalInfo.Value = 0;
            app.IntermediacionLocalInfo.Value = 0;
            app.vectorLocalInfo.Value = 0;
            app.UmbralEditField.Value = 0;
            value_min = str2double("0");
            value_max = str2double("1");
            app.Slider.Limits = [value_min,value_max];
            app.Slider.Value = value_min;
            app.UmbralSliderInfo.Text = "0";
            app.VisualizadorMatriz.Enable = 'on';
            camposIniciales = app.controlador.iniciaCamposNodosEnlaces();
            app.NumNodos.Value = camposIniciales(1,1);
            app.NumEnlaces.Value = camposIniciales(1,2);
            app.ValorDF.Text = "-----";
            app.AplicarboxcoveringButton.Enable = 'off';
            app.Lb_minLabel.Enable = 'off';
            app.Lb_min.Enable = 'off';
            app.Lb_maxLabel.Enable = 'off';
            app.Lb_max.Enable = 'off';
        end
        
        function cargaNuevosDatos(app)
            exito = app.controlador.cargaNuevosDatos();
            
            if(exito)
                app.LabelPanelNodos.Visible = 'on';
                app.CalcularMedidasButton.Visible = 'off';
                app.GradoLabel.Visible = 'off';
                app.CercanaLabel.Visible = 'off';
                app.IntermediacinLabel.Visible = 'off';
                app.CtrVectorPropioLabel.Visible = 'off';
                app.ExportardatosCSVButton.Visible = 'off';
                app.gradoLocalInfo.Visible = 'off';
                app.cercaniaLocalInfo.Visible = 'off';
                app.IntermediacionLocalInfo.Visible = 'off';
                app.vectorLocalInfo.Visible = 'off';
                app.NodoLabel.Visible = 'off';
                app.nameNodo.Visible = 'off';
                app.SeleccionanodoLabel.Visible = 'off';
                app.SeleccionanodoListBox.Visible = 'off';
                app.VisualizadorMatriz.Enable = 'off';
                app.VisualizadorMatriz.ImageSource = "";
                id = app.controlador.centinelaImagen();
                app.LimpiarDatos();
                pause(1);
                source = app.controlador.pintaMatriz(id);
                pause(2);
                app.VisualizadorMatriz.ImageSource = source;
                app.VisualizadorMatriz.Enable = 'on';
            else
                app.controlador.restableceDatosCargados();
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InfoUsuario
        function InfoUsuarioValueChanged(app, event)
          
        end

        % Menu selected function: CargarmatrizadyacenciaMenu
        function SelectedCargarDatos(app, event)
            
            if(app.controlador == "")
                errors = app.iniciaPanel();
                app.InfoUsuario.Value = app.controlador.addText("Cargando datos...");
                if(errors == 0)
                    app.InfoUsuario.Value = app.controlador.addText(" ");
                    app.InfoUsuario.Value = app.controlador.addText("Cancelado");
                    app.controlador = "";
                else
                    app.InfoUsuario.Value = app.controlador.addText(" ");
                    app.Image.Visible = 'off';
                    app.MensajeNoDatos.Visible = 'off';
                    app.PanelMedidas.Visible = 'on';
                    app.NumEnlaces.Visible = 'on';
                    app.NumEnlaces.Editable = 'off';
                    app.NumNodos.Visible = 'on';
                    app.NumNodos.Editable = 'off';
                    app.LabelNodos.Visible = 'on';
                    app.LabelEnlaces.Visible = 'on';
                    app.InfoUsuario.Value = app.controlador.addText("¡Datos cargados con éxito!");
                    app.visualizaMatriz();
                    camposIniciales = app.controlador.iniciaCamposNodosEnlaces();
                    app.NumNodos.Value = camposIniciales(1,1);
                    app.NumEnlaces.Value = camposIniciales(1,2);
                end  
            else
                app.cargaNuevosDatos();
            end
            
            
        end

        % Menu selected function: CargarposicionesnodosMenu
        function cargarDatos3D(app, event)
            if(app.controlador ~= "")
                   if(app.controlador.cargadoDatosRed == 1)
                    app.controlador.cargaCoordenadas3D();
                    
                    if(app.controlador.coordenadasCargadas())
                        app.InfoUsuario.Value = app.controlador.addText("Cargando coordenadas...");
                        app.InfoUsuario.Value = app.controlador.addText("¡Coordenadas cargadas con éxito!");
                        app.LabelPanelNodos.Visible = 'off';
                        app.CalcularMedidasButton.Visible = 'on';
                        app.GradoLabel.Visible = 'on';
                        app.CercanaLabel.Visible = 'on';
                        app.IntermediacinLabel.Visible = 'on';
                        app.CtrVectorPropioLabel.Visible = 'on';
                        app.ExportardatosCSVButton.Visible = 'on';
                        app.gradoLocalInfo.Visible = 'on';
                        app.cercaniaLocalInfo.Visible = 'on';
                        app.IntermediacionLocalInfo.Visible = 'on';
                        app.vectorLocalInfo.Visible = 'on';
                        app.NodoLabel.Visible = 'on';
                        app.nameNodo.Visible = 'on';
                        app.SeleccionanodoLabel.Visible = 'on';
                        app.SeleccionanodoListBox.Visible = 'on';
                        app.SeleccionanodoListBox.Items = app.controlador.getNombreNodos();
                    else
                        app.InfoUsuario.Value = app.controlador.addText("Cancelado");
                    end
                    
                   end
            else
                errordlg('Para poder cargar coordenadas 3D, es necesario que se carguen unos datos de matriz primero.','Fatal Error');
            end
        end

        % Callback function
        function VisualizarmatrizMenuSelected(app, event)
            if(~isempty(app.controlador) && app.controlador.cargadoDatosRed == 1)
                app.VisualizadorMatriz.ImageSource = app.controlador.pintaMatriz();
            else
                errordlg('Para poder visualizar la matriz de adyacencia, es necesario que se carguen unos datos de matriz primero.','Fatal Error');
            end
        end

        % Menu selected function: Visualizar2DMenu
        function Visualizar2DMenuSelected(app, event)
            if(app.controlador ~= "")
                if(app.controlador.coordenadasCargadas())
                    app.controlador.visualiza2D();
                else
                    errordlg('Para poder visualizar la red en 2D, es necesario que se carguen unas posiciones de nodos.','Fatal Error');
                end
            else
                errordlg('Para poder visualizar la red en 2D, es necesario que se carguen unos datos de matriz primero.','Fatal Error');
            end
        end

        % Menu selected function: Visualizar3DMenu
        function Visualizar3DMenuSelected(app, event)
            if(app.controlador ~= "")
                if(app.controlador.coordenadasCargadas())
                    app.controlador.visualiza3D();
                else
                    errordlg('Para poder visualizar la red en 3D, es necesario que se carguen unas posiciones de nodos.','Fatal Error');
                end
            else
                errordlg('Para poder visualizar la red en 3D, es necesario que se carguen unos datos de matriz primero.','Fatal Error');
            end
        end

        % Menu selected function: LimpiardatosMenu
        function LimpiardatosMenuSelected(app, event)
            if(app.controlador ~= "")
                if(app.controlador.cargadoDatosRed == 1)
                   app.VisualizadorMatriz.Enable = 'off';
                   app.gradoInfo.Value = 0; 
                   app.clusteringInfo.Value = 0;
                   app.modularidadInfo.Value = 0;
                   app.ValorDF.Text = "-----";
                   app.gradoLocalInfo.Value = 0;
                   app.cercaniaLocalInfo.Value = 0;
                   app.IntermediacionLocalInfo.Value = 0;
                   app.vectorLocalInfo.Value = 0;
                   app.VisualizadorMatriz.ImageSource = app.controlador.restableceMatriz();
                   app.UmbralEditField.Value = 0;
                   app.UmbralSliderInfo.Text = "0";
                   app.VisualizadorMatriz.Enable = 'on';
                   camposIniciales = app.controlador.iniciaCamposNodosEnlaces();
                   app.NumNodos.Value = camposIniciales(1,1);
                   app.NumEnlaces.Value = camposIniciales(1,2);
                   app.ValorDF.Text = "-----";
                   app.AplicarboxcoveringButton.Enable = 'off';
                   app.Lb_minLabel.Enable = 'off';
                   app.Lb_min.Enable = 'off';
                   app.Lb_maxLabel.Enable = 'off';
                   app.Lb_max.Enable = 'off';
                   value_min = str2double("0");
                   value_max = str2double("1");
                   app.Slider.Limits = [value_min,value_max];
                   app.Slider.Value = value_min;
                end
            else
                errordlg('No hay datos que limpiar.','Fatal Error');
            end
        end

        % Value changed function: UmbralEditField
        function UmbralEditFieldValueChanged(app, event)
            value = app.UmbralEditField.Value;
            app.UmbralSliderInfo.Text = string(value);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.UmbralSliderInfo.Text = string(changingValue);
            
        end

        % Button pushed function: AplicarUmbralButton
        function AplicarUmbralButtonPushed(app, event)
            
            app.InfoUsuario.Value = app.controlador.addText("Aplicando umbral...");
            app.VisualizadorMatriz.Enable = 'off';
            app.VisualizadorMatriz.ImageSource = "";
            
            
            if(string(app.UmbralEditField.Value) == app.UmbralSliderInfo.Text)
                source = app.controlador.aplicaUmbral(app.UmbralEditField.Value);
                pause(2);
                app.VisualizadorMatriz.ImageSource = source;
                app.VisualizadorMatriz.ImageSource = source;
            end
           
            if(string(app.Slider.Value) == app.UmbralSliderInfo.Text)
                source = app.controlador.aplicaUmbral(app.Slider.Value);
                pause(2);
                app.VisualizadorMatriz.ImageSource = source;
                app.VisualizadorMatriz.ImageSource = source;
            end
            
            app.VisualizadorMatriz.Enable = 'on';
            app.InfoUsuario.Value = app.controlador.addText("Umbral aplicado exitosamente");
            camposIniciales = app.controlador.iniciaCamposNodosEnlaces();
            app.NumNodos.Value = camposIniciales(1,1);
            app.NumEnlaces.Value = camposIniciales(1,2);
            
            
            if(app.gradoInfo.Value ~= 0)
               app.gradoInfo.Value = app.controlador.calculaGradoFeatureGlobal();
            end
            
            if(app.clusteringInfo.Value ~= 0)
               app.clusteringInfo.Value = app.controlador.calculaCoefClustering();
            end
            
            if(app.modularidadInfo.Value ~= 0)
               app.modularidadInfo.Value = app.controlador.calculaModularidad();
            end
            
            app.ValorDF.Text = "-----";
            
            app.AplicarboxcoveringButton.Enable = 'off';
            app.Lb_minLabel.Enable = 'off';
            app.Lb_min.Enable = 'off';
            app.Lb_maxLabel.Enable = 'off';
            app.Lb_max.Enable = 'off';
            
            valueUmbral = str2double(app.UmbralSliderInfo.Text);
            
            app.Slider.Limits = [valueUmbral,1];
            
        end

        % Value changed function: SeleccionanodoListBox
        function SeleccionanodoListBoxValueChanged(app, event)
            value = app.SeleccionanodoListBox.Value;
            app.nameNodo.Text = value;
            
            if(app.gradoLocalInfo.Value ~= 0)
               app.gradoLocalInfo.Value = app.controlador.calculaGradoNodo(app.nameNodo.Text);
            end
            
            if(app.cercaniaLocalInfo.Value ~= 0)
               app.cercaniaLocalInfo.Value = app.controlador.calculaCercaniaNodo(app.nameNodo.Text);
            end
            
            if(app.IntermediacionLocalInfo.Value ~= 0)
               app.IntermediacionLocalInfo.Value = app.controlador.calculaIntermediacionNodo(app.nameNodo.Text);
            end
            
            if(app.vectorLocalInfo.Value ~= 0)
               app.vectorLocalInfo.Value = app.controlador.calculaVectorPropio(app.nameNodo.Text);
            end
        end

        % Button pushed function: AplicarboxcoveringButton
        function AplicarboxcoveringButtonPushed(app, event)
            if(app.controlador.coordenadasCargadas())
                lb_min = app.Lb_min.Value;
                lb_max = app.Lb_max.Value;
                
                if(lb_min < lb_max)
                    app.InfoUsuario.Value = app.controlador.addText("Calculando estimación de dimensión fractal...");
                    app.InfoUsuario.Value = app.controlador.addText("Aplicando algoritmo Compact Box Burning basado en box-covering...");
                    app.ValorDF.Text = app.controlador.calculaPendienteEscalaCajas(lb_min,lb_max);
                    app.InfoUsuario.Value = app.controlador.addText("Estimación de dimensión fractal calculada exitosamente");    
                else
                    errordlg('Para aplicar el algoritmo Compact Box Burning es necesario que Lb_min < Lb_max','Fatal Error');
                end
                
            else
                errordlg('Para calcular la dimensión fractal es necesario que introduzca unas posiciones de nodos.','Fatal Error');
            end
        end

        % Button pushed function: ExportardatosCSVButton_2
        function ExportardatosCSVButton_2Pushed(app, event)
            waitfor(warndlg('Antes de exportar los datos se recalcularán todas las medidas','Warning'));
            
            app.InfoUsuario.Value = app.controlador.addText("Exportando medidas globales...");
            app.controlador.exportGlobalFeaturesToCSV();  
            app.InfoUsuario.Value = app.controlador.addText("Datos exportados.");
        end

        % Button pushed function: ExportardatosCSVButton
        function ExportardatosCSVButtonPushed(app, event)
            waitfor(warndlg('Antes de exportar los datos se recalcularán todas las medidas de todos los nodos de la red','Warning'));    
            
            app.InfoUsuario.Value = app.controlador.addText("Exportando medidas locales de todos los nodos...");
            app.controlador.exportLocalFeaturesToCSV();  
            app.InfoUsuario.Value = app.controlador.addText("Datos exportados.");
        end

        % Menu selected function: InformacinadicionalMenu
        function InformacinadicionalMenuSelected(app, event)
           if(app.controlador ~= "")
             message = app.controlador.informacionAdicional();
             waitfor(warndlg(message,'Información Adicional'));   
           end
        end

        % Menu selected function: ContactaconmigoMenu
        function ContactaconmigoMenuSelected(app, event)
            if(app.controlador ~= "")
             message = app.controlador.contactaConmigo();
             waitfor(warndlg(message,'Contacto'));   
           end
        end

        % Button pushed function: VisualizargraficaButton
        function VisualizargraficaButtonPushed(app, event)
            if(app.controlador.coordenadasCargadas())
                if(app.MtodoDropDown.Value == '1')
                    tStart = cputime;
                    app.controlador.calculaGreedyColoring();
                    tEnd = cputime - tStart;
                    ft = "> Greedy Coloring aplicado en " + string(tEnd) + " segundos";
                    app.InfoUsuario.Value = app.controlador.addText(ft);
                end
                if(app.MtodoDropDown.Value == '2')
                    tStart = cputime;
                    app.controlador.calculaCompactBoxBurning();
                    tEnd = cputime - tStart;
                    ft = "> Compact Box Burning aplicado en " + string(tEnd) + " segundos";
                    app.InfoUsuario.Value = app.controlador.addText(ft);
                end
                if(app.MtodoDropDown.Value == '3')
                    tStart = cputime;
                    app.controlador.calculaRandomSequential();
                    tEnd = cputime - tStart;
                    ft = "> Random Sequential aplicado en " + string(tEnd) + " segundos";
                    app.InfoUsuario.Value = app.controlador.addText(ft);
                end
                if(app.MtodoDropDown.Value == '4')
                    tStart = cputime;
                    app.controlador.calculaMergeAlgorithm();
                    tEnd = cputime - tStart;
                    ft = "> Merge Algorithm aplicado en " + string(tEnd) + " segundos";
                    app.InfoUsuario.Value = app.controlador.addText(ft);
                end
                if(app.MtodoDropDown.Value == '5')
                    tStart = cputime;
                    app.controlador.calculaOBCA();
                    tEnd = cputime - tStart;
                    ft = "> Overlapping Box-Covering aplicado en " + string(tEnd) + " segundos";
                    app.InfoUsuario.Value = app.controlador.addText(ft);
                end
                if(app.MtodoDropDown.Value == '6')
                    tStart = cputime;
                    app.controlador.calculaMEMB();
                    tEnd = cputime - tStart;
                    ft = "> Maximal Excluded Mass Burning aplicado en " + string(tEnd) + " segundos";
                    app.InfoUsuario.Value = app.controlador.addText(ft);
                end
                if(app.MtodoDropDown.Value == '7')
                    tStart = cputime;
                    app.controlador.calculaREMCC();
                    tEnd = cputime - tStart;
                    ft = "> Ratio of Excluded Mass to Closeness Centrality aplicado en " + string(tEnd) + " segundos";
                    app.InfoUsuario.Value = app.controlador.addText(ft);
                end
                if(app.MtodoDropDown.Value == '8')
                    tStart = cputime;
                    app.controlador.calculaPSO();
                    tEnd = cputime - tStart;
                    ft = "> Particle Swarm Optimization aplicado en " + string(tEnd) + " segundos";
                    app.InfoUsuario.Value = app.controlador.addText(ft);
                end
                if(app.MtodoDropDown.Value == '9')
                    tStart = cputime;
                    app.controlador.calculaDE();
                    tEnd = cputime - tStart;
                    ft = "> Differential Evolution aplicado en " + string(tEnd) + " segundos";
                    app.InfoUsuario.Value = app.controlador.addText(ft);
                end
                if(app.MtodoDropDown.Value == '10')
                    tStart = cputime;
                    app.controlador.calculaTodos();
                    tEnd = cputime - tStart;
                    ft = "> Todos los algoritmos aplicados en " + string(tEnd) + " segundos";
                    app.InfoUsuario.Value = app.controlador.addText(ft);
                end
                
                app.AplicarboxcoveringButton.Enable = 'on';
                app.Lb_minLabel.Enable = 'on';
                app.Lb_min.Enable = 'on';
                app.Lb_maxLabel.Enable = 'on';
                app.Lb_max.Enable = 'on';
            else
                errordlg('Para calcular la dimensión fractal es necesario que introduzca unas posiciones de nodos.','Fatal Error');
            end
        end

        % Menu selected function: SalirMenu
        function SalirMenuSelected(app, event)
            if(app.controlador ~= "")
                app.controlador.borraImagenes();
            end
            close all force;
        end

        % Close request function: AnlisisderedescerebralesUIFigure
        function AnlisisderedescerebralesUIFigureCloseRequest(app, event)
            if(app.controlador ~= "")
                app.controlador.borraImagenes();
                delete(app);
            end
            close all force;
        end

        % Button pushed function: allFeatures
        function allFeaturesButtonPushed(app, event)
            app.InfoUsuario.Value = app.controlador.addText("Calculando todas las medidas...");
            app.gradoInfo.Value = app.controlador.calculaGradoFeatureGlobal();
            app.clusteringInfo.Value = app.controlador.calculaCoefClustering();
            app.modularidadInfo.Value = app.controlador.calculaModularidad();
            app.InfoUsuario.Value = app.controlador.addText("¡Calculadas!");
        end

        % Button pushed function: CalcularMedidasButton
        function CalcularMedidasButtonPushed(app, event)
            nameNodoSelected = app.nameNodo.Text;
            
            if(nameNodoSelected ~= "-----")
                app.gradoLocalInfo.Value = app.controlador.calculaGradoNodo(nameNodoSelected);
                app.cercaniaLocalInfo.Value = app.controlador.calculaCercaniaNodo(nameNodoSelected);
                app.IntermediacionLocalInfo.Value = app.controlador.calculaIntermediacionNodo(nameNodoSelected);
                app.vectorLocalInfo.Value = app.controlador.calculaVectorPropio(nameNodoSelected);
            else
               errordlg('Selecciona un nodo de la lista.','Fatal Error'); 
            end
            
        end

        % Menu selected function: ManualdeusuarioMenu
        function ManualdeusuarioMenuSelected(app, event)
            winopen("ManualDeUsuario.pdf");
        end

        % Value changed function: MtodoDropDown
        function MtodoDropDownValueChanged(app, event)
            value = app.MtodoDropDown.Value;
            metodoSeleccionado = value
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnlisisderedescerebralesUIFigure = uifigure('Visible', 'off');
            app.AnlisisderedescerebralesUIFigure.Name = 'Análisis de redes cerebrales';
            app.AnlisisderedescerebralesUIFigure.CloseRequestFcn = createCallbackFcn(app, @AnlisisderedescerebralesUIFigureCloseRequest, true);
            app.ArchivoMenu = uimenu(app.AnlisisderedescerebralesUIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.CargarmatrizadyacenciaMenu = uimenu(app.ArchivoMenu);
            app.CargarmatrizadyacenciaMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectedCargarDatos, true);
            app.CargarmatrizadyacenciaMenu.Text = 'Cargar matriz adyacencia';
            app.CargarposicionesnodosMenu = uimenu(app.ArchivoMenu);
            app.CargarposicionesnodosMenu.MenuSelectedFcn = createCallbackFcn(app, @cargarDatos3D, true);
            app.CargarposicionesnodosMenu.Text = 'Cargar posiciones nodos';
            app.SalirMenu = uimenu(app.ArchivoMenu);
            app.SalirMenu.MenuSelectedFcn = createCallbackFcn(app, @SalirMenuSelected, true);
            app.SalirMenu.Text = 'Salir';
            app.EditarMenu = uimenu(app.AnlisisderedescerebralesUIFigure);
            app.EditarMenu.Text = 'Editar';
            app.LimpiardatosMenu = uimenu(app.EditarMenu);
            app.LimpiardatosMenu.MenuSelectedFcn = createCallbackFcn(app, @LimpiardatosMenuSelected, true);
            app.LimpiardatosMenu.Text = 'Limpiar datos';
            app.VisualizarMenu = uimenu(app.AnlisisderedescerebralesUIFigure);
            app.VisualizarMenu.Text = 'Visualizar';
            app.Visualizar2DMenu = uimenu(app.VisualizarMenu);
            app.Visualizar2DMenu.MenuSelectedFcn = createCallbackFcn(app, @Visualizar2DMenuSelected, true);
            app.Visualizar2DMenu.Text = 'Visualizar 2D';
            app.Visualizar3DMenu = uimenu(app.VisualizarMenu);
            app.Visualizar3DMenu.MenuSelectedFcn = createCallbackFcn(app, @Visualizar3DMenuSelected, true);
            app.Visualizar3DMenu.Text = 'Visualizar 3D';
            app.AyudaMenu = uimenu(app.AnlisisderedescerebralesUIFigure);
            app.AyudaMenu.Text = 'Ayuda';
            app.InformacinadicionalMenu = uimenu(app.AyudaMenu);
            app.InformacinadicionalMenu.MenuSelectedFcn = createCallbackFcn(app, @InformacinadicionalMenuSelected, true);
            app.InformacinadicionalMenu.Text = 'Información adicional';
            app.ContactaconmigoMenu = uimenu(app.AyudaMenu);
            app.ContactaconmigoMenu.MenuSelectedFcn = createCallbackFcn(app, @ContactaconmigoMenuSelected, true);
            app.ContactaconmigoMenu.Text = 'Contacta conmigo';
            app.ManualdeusuarioMenu = uimenu(app.AyudaMenu);
            app.ManualdeusuarioMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualdeusuarioMenuSelected, true);
            app.ManualdeusuarioMenu.Text = 'Manual de usuario';
            app.InfoUsuario = uitextarea(app.AnlisisderedescerebralesUIFigure);
            app.InfoUsuario.ValueChangedFcn = createCallbackFcn(app, @InfoUsuarioValueChanged, true);
            app.InfoUsuario.Editable = 'off';
            app.InfoUsuario.Value = {'> Es necesario cargar unos datos...'};
            app.NumNodos = uieditfield(app.AnlisisderedescerebralesUIFigure, 'numeric');
            app.NumNodos.Editable = 'off';
            app.NumNodos.Visible = 'off';
            app.PanelMedidas = uitabgroup(app.AnlisisderedescerebralesUIFigure);
            app.MedidasderedTab = uitab(app.PanelMedidas);
            app.MedidasderedTab.Title = 'Medidas de red';
            app.Slider = uislider(app.MedidasderedTab);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.gradoInfo = uieditfield(app.MedidasderedTab, 'numeric');
            app.gradoInfo.Editable = 'off';
            app.modularidadInfo = uieditfield(app.MedidasderedTab, 'numeric');
            app.modularidadInfo.Editable = 'off';
            app.Label = uilabel(app.MedidasderedTab);
            app.Label.Text = '{';
            app.UmbralEditFieldLabel = uilabel(app.MedidasderedTab);
            app.UmbralEditFieldLabel.Text = 'Umbral';
            app.UmbralEditField = uieditfield(app.MedidasderedTab, 'numeric');
            app.UmbralEditField.Limits = [0 1];
            app.UmbralEditField.ValueChangedFcn = createCallbackFcn(app, @UmbralEditFieldValueChanged, true);
            app.UmbralSliderInfo = uilabel(app.MedidasderedTab);
            app.UmbralSliderInfo.Text = '0';
            app.AplicarUmbralButton = uibutton(app.MedidasderedTab, 'push');
            app.AplicarUmbralButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarUmbralButtonPushed, true);
            app.AplicarUmbralButton.Text = {'Aplicar umbral'; ''};
            app.clusteringInfo = uieditfield(app.MedidasderedTab, 'numeric');
            app.clusteringInfo.Editable = 'off';
            app.ExportardatosCSVButton_2 = uibutton(app.MedidasderedTab, 'push');
            app.ExportardatosCSVButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportardatosCSVButton_2Pushed, true);
            app.ExportardatosCSVButton_2.Text = 'Exportar datos CSV';
            app.DimensinFractalPanel = uipanel(app.MedidasderedTab);
            app.DimensinFractalPanel.Title = 'Dimensión Fractal';
            app.Lb_min = uieditfield(app.DimensinFractalPanel, 'numeric');
            app.Lb_min.Limits = [1 Inf];
            app.Lb_min.ValueDisplayFormat = '%.0f';
            app.Lb_min.Enable = 'off';
            app.Lb_min.Value = 1;
            app.Lb_minLabel = uilabel(app.DimensinFractalPanel);
            app.Lb_minLabel.Enable = 'off';
            app.Lb_minLabel.Text = 'Lb_min';
            app.ResultadoLabel = uilabel(app.DimensinFractalPanel);
            app.ResultadoLabel.Text = 'Resultado';
            app.ValorDF = uilabel(app.DimensinFractalPanel);
            app.ValorDF.Text = '-----';
            app.Lb_maxLabel = uilabel(app.DimensinFractalPanel);
            app.Lb_maxLabel.Enable = 'off';
            app.Lb_maxLabel.Text = 'Lb_max';
            app.Lb_max = uieditfield(app.DimensinFractalPanel, 'numeric');
            app.Lb_max.Limits = [0 Inf];
            app.Lb_max.ValueDisplayFormat = '%.0f';
            app.Lb_max.Enable = 'off';
            app.Lb_max.Value = 1;
            app.VisualizargraficaButton = uibutton(app.DimensinFractalPanel, 'push');
            app.VisualizargraficaButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizargraficaButtonPushed, true);
            app.VisualizargraficaButton.Text = 'Seleccionar boxes';
            app.AplicarboxcoveringButton = uibutton(app.DimensinFractalPanel, 'push');
            app.AplicarboxcoveringButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarboxcoveringButtonPushed, true);
            app.AplicarboxcoveringButton.Enable = 'off';
            app.AplicarboxcoveringButton.Text = 'Aplicar box-covering';
            app.MtodoDropDownLabel = uilabel(app.DimensinFractalPanel);
            app.MtodoDropDownLabel.Text = 'Método';
            app.MtodoDropDown = uidropdown(app.DimensinFractalPanel);
            app.MtodoDropDown.Items = {'Greedy Coloring', 'Compact Box Burning', 'Random Sequential', 'Merge', 'OBCA', 'MEMB', 'REMCC', 'PSO', 'DE', 'Usar Todos'};
            app.MtodoDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.MtodoDropDown.ValueChangedFcn = createCallbackFcn(app, @MtodoDropDownValueChanged, true);
            app.MtodoDropDown.Value = '1';
            app.allFeatures = uibutton(app.MedidasderedTab, 'push');
            app.allFeatures.ButtonPushedFcn = createCallbackFcn(app, @allFeaturesButtonPushed, true);
            app.allFeatures.Text = {'Calcular '; 'medidas'};
            app.GradomedioLabel = uilabel(app.MedidasderedTab);
            app.GradomedioLabel.Text = 'Grado medio';
            app.CoefClusteringLabel = uilabel(app.MedidasderedTab);
            app.CoefClusteringLabel.Text = 'Coef. Clustering';
            app.ModularidadLabel = uilabel(app.MedidasderedTab);
            app.ModularidadLabel.Text = 'Modularidad';
            app.MedidasdenodosTab = uitab(app.PanelMedidas);
            app.MedidasdenodosTab.Title = 'Medidas de nodos';
            app.ExportardatosCSVButton = uibutton(app.MedidasdenodosTab, 'push');
            app.ExportardatosCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportardatosCSVButtonPushed, true);
            app.ExportardatosCSVButton.Visible = 'off';
            app.ExportardatosCSVButton.Text = 'Exportar datos CSV';
            app.gradoLocalInfo = uieditfield(app.MedidasdenodosTab, 'numeric');
            app.gradoLocalInfo.Editable = 'off';
            app.gradoLocalInfo.Visible = 'off';
            app.cercaniaLocalInfo = uieditfield(app.MedidasdenodosTab, 'numeric');
            app.cercaniaLocalInfo.Editable = 'off';
            app.cercaniaLocalInfo.Visible = 'off';
            app.IntermediacionLocalInfo = uieditfield(app.MedidasdenodosTab, 'numeric');
            app.IntermediacionLocalInfo.Editable = 'off';
            app.IntermediacionLocalInfo.Visible = 'off';
            app.vectorLocalInfo = uieditfield(app.MedidasdenodosTab, 'numeric');
            app.vectorLocalInfo.Editable = 'off';
            app.vectorLocalInfo.Visible = 'off';
            app.NodoLabel = uilabel(app.MedidasdenodosTab);
            app.NodoLabel.Visible = 'off';
            app.NodoLabel.Text = 'Nodo:';
            app.nameNodo = uilabel(app.MedidasdenodosTab);
            app.nameNodo.Visible = 'off';
            app.nameNodo.Text = '-----';
            app.SeleccionanodoListBox = uilistbox(app.MedidasdenodosTab);
            app.SeleccionanodoListBox.Items = {};
            app.SeleccionanodoListBox.ValueChangedFcn = createCallbackFcn(app, @SeleccionanodoListBoxValueChanged, true);
            app.SeleccionanodoListBox.Visible = 'off';
            app.SeleccionanodoListBox.Value = {};
            app.SeleccionanodoLabel = uilabel(app.MedidasdenodosTab);
            app.SeleccionanodoLabel.Visible = 'off';
            app.SeleccionanodoLabel.Text = {'Selecciona'; 'nodo'};
            app.LabelPanelNodos = uilabel(app.MedidasdenodosTab);
            app.LabelPanelNodos.Text = {'Para poder usar este panel es '; 'necesario que cargue unos datos'; 'de coordenadas 3D'};
            app.CalcularMedidasButton = uibutton(app.MedidasdenodosTab, 'push');
            app.CalcularMedidasButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularMedidasButtonPushed, true);
            app.CalcularMedidasButton.Visible = 'off';
            app.CalcularMedidasButton.Text = {'Calcular'; 'Medidas'};
            app.GradoLabel = uilabel(app.MedidasdenodosTab);
            app.GradoLabel.Visible = 'off';
            app.GradoLabel.Text = 'Grado';
            app.CercanaLabel = uilabel(app.MedidasdenodosTab);
            app.CercanaLabel.Visible = 'off';
            app.CercanaLabel.Text = 'Cercanía';
            app.IntermediacinLabel = uilabel(app.MedidasdenodosTab);
            app.IntermediacinLabel.Visible = 'off';
            app.IntermediacinLabel.Text = 'Intermediación';
            app.CtrVectorPropioLabel = uilabel(app.MedidasdenodosTab);
            app.CtrVectorPropioLabel.Visible = 'off';
            app.CtrVectorPropioLabel.Text = 'Ctr. Vector Propio';
            app.LabelNodos = uilabel(app.AnlisisderedescerebralesUIFigure);
            app.LabelNodos.Visible = 'off';
            app.LabelNodos.Text = 'Nº Nodos';
            app.LabelEnlaces = uilabel(app.AnlisisderedescerebralesUIFigure);
            app.LabelEnlaces.Visible = 'off';
            app.LabelEnlaces.Text = 'Nº Enlaces';
            app.NumEnlaces = uieditfield(app.AnlisisderedescerebralesUIFigure, 'numeric');
            app.NumEnlaces.Editable = 'off';
            app.NumEnlaces.Visible = 'off';
            app.MensajeNoDatos = uilabel(app.AnlisisderedescerebralesUIFigure);
            app.MensajeNoDatos.Text = 'No hay datos cargados...';
            app.Image = uiimage(app.AnlisisderedescerebralesUIFigure);
            app.Image.ImageSource = 'logoNoDatos.png';
            app.VisualizadorMatriz = uiimage(app.AnlisisderedescerebralesUIFigure);
            app.VisualizadorMatriz.Visible = 'off';
            % Show the figure after all components are created
            app.AnlisisderedescerebralesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnlisisderedescerebralesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnlisisderedescerebralesUIFigure)
        end
    end
end